classdef plotingdifferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        CapacityLabel  matlab.ui.control.Label
        cap            matlab.ui.control.NumericEditField
        CostLabel      matlab.ui.control.Label
        cost           matlab.ui.control.NumericEditField
        LambdaLabel    matlab.ui.control.Label
        lambda         matlab.ui.control.NumericEditField
        Plot           matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
        Accept         matlab.ui.control.CheckBox
        Reject         matlab.ui.control.CheckBox
        Optimal        matlab.ui.control.CheckBox
        Policy4        matlab.ui.control.CheckBox
        Policy5        matlab.ui.control.CheckBox
        Policy6        matlab.ui.control.CheckBox
        Policy7        matlab.ui.control.CheckBox
        Policy8        matlab.ui.control.CheckBox
        Policy9        matlab.ui.control.CheckBox
        UIAxes_2       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: cap
        function capValueChanged(app, event)
            value = app.cap.Value;
        end

        % Value changed function: cost
        function costValueChanged(app, event)
            value = app.cost.Value;
        end

        % Value changed function: lambda
        function lambdaValueChanged(app, event)
            value = app.lambda.Value;
        end

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
            set(groot,'defaultAxesLineStyleOrder',{'-'});
            k = app.cap.Value;
            i = app.cost.Value;
            j = app.lambda.Value;
            L1 = xlsread(strcat(num2str(10*k),'Datmodi.xlsx'),strcat(num2str(k),'-',num2str(i),'-',num2str(j)));
            L2 = xlsread(strcat(num2str(10*k),'Datmodis.xlsx'),strcat(num2str(k),'-',num2str(i),'-',num2str(j)));
            x1 = L1(1:end,1);
            x2 = L2(1:end,1);
            z(1) = double(app.Accept.Value);
            z(2) = double(app.Reject.Value);
            z(3) = double(app.Optimal.Value);
            z(4) = double(app.Policy4.Value);
            z(5) = double(app.Policy5.Value);
            z(6) = double(app.Policy6.Value);
            z(7) = double(app.Policy7.Value);
            z(8) = double(app.Policy8.Value);
            z(9) = double(app.Policy9.Value);
            m = find(z(:,:))+1;
            plot(app.UIAxes,x1,L1(1:end,m));
%             set(groot,'defaultAxesLineStyleOrder',{'-'});
            plot(app.UIAxes_2,x2,L2(1:end,m));
%             app.Table.RowName = {'Cust1';'Cust2';'Cust3';'Cust4';'Cust5'};
%             rw = (5*(i-1))+j;
%             s = table;
%             s = xlsread(strcat(num2str(10*k),'.xlsx'),5);
%             app.Table.Data = [s(rw,3:11)',s(rw,12:20)',s(rw,21:29)',s(rw,30:38)'];
        end

        % Value changed function: Accept
        function AcceptValueChanged(app, event)
            value = app.Accept.Value;            
        end

        % Value changed function: Reject
        function RejectValueChanged(app, event)
            value = app.Reject.Value;            
        end

        % Value changed function: Optimal
        function OptimalValueChanged(app, event)
            value = app.Optimal.Value;            
        end

        % Value changed function: Policy4
        function Policy4ValueChanged(app, event)
            value = app.Policy4.Value;            
        end

        % Value changed function: Policy5
        function Policy5ValueChanged(app, event)
            value = app.Policy5.Value;            
        end

        % Value changed function: Policy6
        function Policy6ValueChanged(app, event)
            value = app.Policy6.Value;            
        end

        % Value changed function: Policy7
        function Policy7ValueChanged(app, event)
            value = app.Policy7.Value;            
        end

        % Value changed function: Policy8
        function Policy8ValueChanged(app, event)
            value = app.Policy8.Value;            
        end

        % Value changed function: Policy9
        function Policy9ValueChanged(app, event)
            value = app.Policy9.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CapacityLabel = uilabel(app.UIFigure);
            app.CapacityLabel.Text = 'Capacity';
            app.cap = uieditfield(app.UIFigure, 'numeric');
            app.cap.Limits = [1 10];
            app.cap.ValueDisplayFormat = '%.0f';
            app.cap.ValueChangedFcn = createCallbackFcn(app, @capValueChanged, true);
            app.cap.Value = 1;
            app.CostLabel = uilabel(app.UIFigure);
            app.CostLabel.Text = 'Cost';
            app.cost = uieditfield(app.UIFigure, 'numeric');
            app.cost.Limits = [1 10];
            app.cost.ValueDisplayFormat = '%.0f';
            app.cost.ValueChangedFcn = createCallbackFcn(app, @costValueChanged, true);
            app.cost.Value = 1;
            app.LambdaLabel = uilabel(app.UIFigure);
            app.LambdaLabel.Text = 'Lambda';
            app.lambda = uieditfield(app.UIFigure, 'numeric');
            app.lambda.Limits = [1 10];
            app.lambda.ValueDisplayFormat = '%.0f';
            app.lambda.ValueChangedFcn = createCallbackFcn(app, @lambdaValueChanged, true);
            app.lambda.Value = 1;
            app.Plot = uibutton(app.UIFigure, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Policy errors with Actual Bounds')
            xlabel(app.UIAxes, 'mu')
            ylabel(app.UIAxes, 'Errors')
            app.UIAxes.LineStyleOrder = {'- '};
            app.Accept = uicheckbox(app.UIFigure);
            app.Accept.ValueChangedFcn = createCallbackFcn(app, @AcceptValueChanged, true);
            app.Accept.Text = 'Accept';
            app.Accept.Value = true;
            app.Reject = uicheckbox(app.UIFigure);
            app.Reject.ValueChangedFcn = createCallbackFcn(app, @RejectValueChanged, true);
            app.Reject.Text = 'Reject';
            app.Reject.Value = true;
            app.Optimal = uicheckbox(app.UIFigure);
            app.Optimal.ValueChangedFcn = createCallbackFcn(app, @OptimalValueChanged, true);
            app.Optimal.Text = 'Optimal';
            app.Optimal.Value = true;
            app.Policy4 = uicheckbox(app.UIFigure);
            app.Policy4.ValueChangedFcn = createCallbackFcn(app, @Policy4ValueChanged, true);
            app.Policy4.Text = 'Policy 4';
            app.Policy4.Value = true;
            app.Policy5 = uicheckbox(app.UIFigure);
            app.Policy5.ValueChangedFcn = createCallbackFcn(app, @Policy5ValueChanged, true);
            app.Policy5.Text = 'Policy 5';
            app.Policy5.Value = true;
            app.Policy6 = uicheckbox(app.UIFigure);
            app.Policy6.ValueChangedFcn = createCallbackFcn(app, @Policy6ValueChanged, true);
            app.Policy6.Text = 'Policy 6';
            app.Policy6.Value = true;
            app.Policy7 = uicheckbox(app.UIFigure);
            app.Policy7.ValueChangedFcn = createCallbackFcn(app, @Policy7ValueChanged, true);
            app.Policy7.Text = 'Policy 7';
            app.Policy7.Value = true;
            app.Policy8 = uicheckbox(app.UIFigure);
            app.Policy8.ValueChangedFcn = createCallbackFcn(app, @Policy8ValueChanged, true);
            app.Policy8.Text = 'Policy 8';
            app.Policy8.Value = true;
            app.Policy9 = uicheckbox(app.UIFigure);
            app.Policy9.ValueChangedFcn = createCallbackFcn(app, @Policy9ValueChanged, true);
            app.Policy9.Text = 'Policy 9';
            app.Policy9.Value = true;
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Policy errors With Estimated Bounds')
            xlabel(app.UIAxes_2, 'mu')
            ylabel(app.UIAxes_2, 'Errors')
            app.UIAxes_2.LineStyleOrder = {'- '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotingdifferences

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end